classdef Carriers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataFittingStatusEditFieldLabel  matlab.ui.control.Label
        ToolStatus                      matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        InputparametersandusercontrolPanel  matlab.ui.container.Panel
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        IntentionaldopingCheckBox       matlab.ui.control.CheckBox
        DopingTypeDropDownLabel         matlab.ui.control.Label
        DopingDropDown                  matlab.ui.control.DropDown
        DopingDensityEditFieldLabel     matlab.ui.control.Label
        DopingDensityEditField          matlab.ui.control.NumericEditField
        X1e15cm3Label                   matlab.ui.control.Label
        DefectLevel1EditFieldLabel      matlab.ui.control.Label
        DefectLevel1EditField           matlab.ui.control.NumericEditField
        meVLabel_7                      matlab.ui.control.Label
        FixSRH1CheckBox                 matlab.ui.control.CheckBox
        DefectDensity1EditFieldLabel    matlab.ui.control.Label
        DefectDensity1EditField         matlab.ui.control.NumericEditField
        m1Label_7                       matlab.ui.control.Label
        DefectLevel2EditFieldLabel      matlab.ui.control.Label
        DefectLevel2EditField           matlab.ui.control.NumericEditField
        meVLabel_8                      matlab.ui.control.Label
        DefectDensity2EditFieldLabel    matlab.ui.control.Label
        DefectDensity2EditField         matlab.ui.control.NumericEditField
        m1Label_8                       matlab.ui.control.Label
        BlochoverlapEditFieldLabel      matlab.ui.control.Label
        BlochoverlapEditField           matlab.ui.control.NumericEditField
        FitBlochCheckBox                matlab.ui.control.CheckBox
        ActiveRegionThicknessEditFieldLabel  matlab.ui.control.Label
        ActiveRegionThicknessEditField  matlab.ui.control.NumericEditField
        umLabel                         matlab.ui.control.Label
        CaplayerrefractiveindexatbandgapenergyEditFieldLabel  matlab.ui.control.Label
        CaplayerrefractiveindexatbandgapenergyEditField  matlab.ui.control.NumericEditField
        FitMCheckBox                    matlab.ui.control.CheckBox
        mhEditFieldLabel                matlab.ui.control.Label
        mhEditField                     matlab.ui.control.NumericEditField
        meEditFieldLabel                matlab.ui.control.Label
        meEditField                     matlab.ui.control.NumericEditField
        m0Label                         matlab.ui.control.Label
        FixDefectLevel1CheckBox         matlab.ui.control.CheckBox
        DataFittingPanel                matlab.ui.container.Panel
        TRPLFittedresultsMATfileLabel   matlab.ui.control.Label
        TRPLMATfilePath                 matlab.ui.control.EditField
        PLFittedresultsMATfileEditFieldLabel  matlab.ui.control.Label
        PLMATfilePath                   matlab.ui.control.EditField
        StartButton                     matlab.ui.control.Button
        SimulateButton                  matlab.ui.control.Button
        FitButton                       matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        AbortButton                     matlab.ui.control.Button
        NoPLDataCheckBox                matlab.ui.control.CheckBox
        OutputfilesuffixLabel           matlab.ui.control.Label
        UserOutputFileSuffix            matlab.ui.control.EditField
        ShowResultsCheckBox             matlab.ui.control.CheckBox
        createtxtFilesCheckBox          matlab.ui.control.CheckBox
        LowestExcitationCheckBox        matlab.ui.control.CheckBox
        ExcitationDropDownLabel         matlab.ui.control.Label
        ExcitationDropDown              matlab.ui.control.DropDown
        ErrorEditFieldLabel             matlab.ui.control.Label
        ErrorReport                     matlab.ui.control.EditField
        FittingLimitsadvanceddefaultsshownPanel  matlab.ui.container.Panel
        FittoTaboveCheckBox             matlab.ui.control.CheckBox
        X1e15cm3Label_2                 matlab.ui.control.Label
        LowTlimitEditFieldLabel         matlab.ui.control.Label
        LowTlimitEditField              matlab.ui.control.NumericEditField
        Uppern0limitEditFieldLabel      matlab.ui.control.Label
        Uppern0limitEditField           matlab.ui.control.NumericEditField
        Lowern0LimitEditFieldLabel      matlab.ui.control.Label
        Lowern0LimitEditField           matlab.ui.control.NumericEditField
        X1e15cm3Label_3                 matlab.ui.control.Label
        LowerDefectLevelLimitEditFieldLabel  matlab.ui.control.Label
        LowerDefectLevelLimitEditField  matlab.ui.control.NumericEditField
        meVLabel_9                      matlab.ui.control.Label
        UpperDefectLevelLimitEditFieldLabel  matlab.ui.control.Label
        UpperDefectLevelLimitEditField  matlab.ui.control.NumericEditField
        meVLabel_10                     matlab.ui.control.Label
        LowerDefectDensityLimitEditFieldLabel  matlab.ui.control.Label
        LowerDefectDensityLimitEditField  matlab.ui.control.NumericEditField
        m1Label_9                       matlab.ui.control.Label
        UpperDefectDensityLimitEditFieldLabel  matlab.ui.control.Label
        UpperDefectDensityLimitEditField  matlab.ui.control.NumericEditField
        m1Label_10                      matlab.ui.control.Label
        LowerF1F2LimitEditFieldLabel    matlab.ui.control.Label
        LowerF1F2LimitEditField         matlab.ui.control.NumericEditField
        UpperF1F2LimitLabel             matlab.ui.control.Label
        UpperF1F2LimitEditField         matlab.ui.control.NumericEditField
        RNGseedEditFieldLabel           matlab.ui.control.Label
        RNGseedEditField                matlab.ui.control.NumericEditField
        NumberofGuessesEditFieldLabel   matlab.ui.control.Label
        NumberofGuessesEditField        matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        WeightedDataCheckBox            matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function startButtonPushed(app, event)
            
            % Reset the error report and background return
            app.ErrorReport.Value = '';
            
            % Empty vectors from workspace that could confuse the tool if subsequent load steps fail
            Carrier_WorkspaceReset
            % Global variables for Previous/Repeat/Advance/Abort button presses
            global simulateData
            global fitData
            global saveResults
            global abortNow
            
            Carrier_Starting
            % Get the user specified MAT File
            DataToAnalyze1 = app.TRPLMATfilePath.Value;
            DataToAnalyze2 = app.PLMATfilePath.Value;
            singleExcitation = app.LowestExcitationCheckBox.Value;
            
            
            noPLData = app.NoPLDataCheckBox.Value;
            % MAT File should contain the following
            %   Temperatures:      Temperatures for each acquisition, row vector in units of K
            %   Excitations:       Excitations for each acquisition, col vector, units of deg (TRPL) or V (TMR)
            %   SampleName:        Sample name and identifying information
            %   t0_vTemp:          lifetime calculated to extract the data
            %   t0Sigma_vTemp:     lifetime error uncertainty (It will be
            %   accommadated if the sample does not contain the error)
            clear('Temperatures', 'Excitations','SampleName','t0_vTemp','t0Sigma_vTemp');
            
            lastwarn('','')
            
            try
                load( DataToAnalyze1 ,  'Temperatures' , 'Excitations' ,...
                    'SampleName', 't0_vTemp','t0Sigma_vTemp');
            catch
                ErrorMessage = 'Problem trying to load TRPL MAT file at specified path';
                app.ErrorReport.Value = ErrorMessage;
                error(ErrorMessage)
            end
            [warnmsg,warnid] = lastwarn();
            if(~isempty(warnmsg))
                t0Sigma_vTemp =NaN*ones(size(t0_vTemp));
            end


            % Create a color wheel to overlay temperature-dependent data, different colors with each excitation level
            ColorWheel = [ 'bo-' ; 'go-' ; 'ro-' ; 'ko-' ; 'co-' ; 'yo-' ];
            
            % Create a color wheel to overlay temperature-dependent simulated data, different styles with each
            % recombination component
            ColorWheel2 = [ "k--" ; "k-." ; "k:" ; "k-"; "-.r*"];
            srh2MarkerSize = 1.5;
            
            
            adaptive_Ind = 1;
            
            %Plot as a function of excitations
            for exciteInd = 1:length(Excitations)
                
                if(size(t0Sigma_vTemp,2) > 0)
                    errorbar(app.UIAxes,Temperatures, t0_vTemp(adaptive_Ind,:,exciteInd),t0Sigma_vTemp(adaptive_Ind,:,exciteInd),...
                        ColorWheel(exciteInd,:),'Displayname', string(Excitations(exciteInd)))
                else
                    plot(app.UIAxes, Temperatures, t0_vTemp(adaptive_Ind,:,exciteInd),...
                        ColorWheel(exciteInd,:),'Displayname', string(Excitations(exciteInd)))
                    
                end
                hold(app.UIAxes,'on')
            end
            legend(app.UIAxes)
            
            hold(app.UIAxes,'off')
            dropDownItems = cell(1,length(Excitations) + 2);
            dropDownItems{1} = 'All';
            dropDownItems{end} = 'Average';
            for i = 1:length(Excitations)
                dropDownItems{i+1} = num2str(Excitations(i));
            end
            app.ExcitationDropDown.Items = dropDownItems;
            
            
            %app.ExcitationDropDown.Items = Excit
            
            
            % Initialize parameters that will be used in the simulation
            % The database from the CSV will be read and temperature probes
            % are initialized here too
            materialsDataBase = readtable('BandParameters.csv');
            
            % have a temperature simulation from 50-330 K in steps of 1 K
            beginTemp = 50;
            endTemp = 330;
            stepNumber = endTemp - beginTemp +1;
            Tprobe = linspace(beginTemp,endTemp,stepNumber)     ;
            tauAug = [];
            tauRad = [];
            tauSRH = [];
            totalLifetime = [];
            
            
            simulateData = 0;
            fitData = 0;
            saveResults = 0;
            abortNow = 0;
            
            
            % Reset WaitingForUser to 1 to hold in while-loop
            waitingForUser = 1;
            
            % WhileBreaker variable increments to time out the while-loop
            whileBreaker = 0;
            
            % Define while-loop time increment
            whileIterationTime = 0.05; % 50 ms iterations
            
            % Define while-loop total time limit to break loop
            whileTimeLimit = 360; % 360 seconds = 5 minutes
            whileIterationLimit = whileTimeLimit/whileIterationTime;
            
            % Operations started, set status to active
            Carrier_Activate;
            while(waitingForUser)
                
                % Wait the while-loop time incre
                pause(whileIterationTime)
                
                % Increment WhileBreaker
                whileBreaker = whileBreaker + 1;


                % Simulate the data from the parameters box
                if(simulateData)
                    material = '';
                    bandParams = [];
                    materialDopingType = '';
                    dopingDensity = nan;
                    defectDensity = nan;
                    defectLevel  = nan;
                    DataToAnalyze2 = '';
                    Nc  = nan;
                    Nv  = nan;
                    ni  = nan;
                    G   = nan;
                    phi = nan;


                    %   materialsDataBase = readtable('BandParameters.csv');
                    material = app.MaterialDropDown.Value;
                    bandParams = nan*ones(10,1);
                    
                    
                    switch(material)
                        case 'InAs'
                            bandParams =materialsDataBase.InAs;
                            
                        case 'GaAs'
                            bandParams = materialsDataBase.GaAs;
                        case 'InSb'
                            bandParams = materialsDataBase.InSb;
                        case 'InAsSb'
                            % use InAs band parameters
                            bandParams = materialsDataBase.InAs;
                        case 'Ge'
                            bandParams = materialsDataBase.Ge;
                        otherwise
                            bandParams = materialsDataBase.InAs;
                            
                            
                    end
                    % Band Parameters
                    %row1     =  Band gap in eV (0 K) perhaps
                    %row2     =  electron effective mass in units of free electron mass
                    %row3     =  gamma1 inverse effective mass parameter 1
                    %row4     =  gamma2 inverse effective mass parameter 2
                    %row5     =  gamma3 inverse effective mass parameter 3
                    %row6     =  alpha 0 K band gap using Varshni equation
                    %row7     =  beta  slope of the Varshni equation
                    %row8     =  einf static dielectric constant
                    %row9     =  F1F2  Auger overlap integral parameter
                    %row10    =  Absorption coefficient at the band gap
                    
                    type = 'i';
                    materialDopingType = app.DopingDropDown.Value;
                    % Check the doping Type
                    switch(materialDopingType)
                        case('Intrinsic')
                            type = 'i';
                        case('p-type')
                            type ='p';
                        case('n-type')
                            type = 'n';
                            
                    end
                    %Doping density input will be in units of 1e15 cm^-3
                    dopingDensity = app.DopingDensityEditField.Value;
                    
                    %Defect density in units of m^-1
                    defectDensity = app.DefectDensity1EditField.Value;
                    
                    % Units of meV, so change to eV The negative shows that
                    % the conduction band edge is at zero eV
                    defectLevel = -app.DefectLevel1EditField.Value/1000;
                    %defectLevel = app.DefectLevel1EditField.Value/1000;
                    
                    % Bloch overlap integral value
                    blochOverlap = app.BlochoverlapEditField.Value;
                    
                    % For the case of intentional doping
                    defectDensity2 = app.DefectDensity2EditField.Value;
                    defectLevel2 = -app.DefectLevel2EditField.Value/1000;


                    % extract the band parameters and use the Varshni equation
                    % with given parameters from the Vurgaftman review article
                    % See the CSV named bandParameters.csv to look at the
                    % input parameters
                    %[meStar, mhStar, eg, nRefractive,...
                     %   kExtinction, photonEnergyum, varshniEinstein, einf, f1f2,ag] = extractParameters(bandParams, material);
                     
                     
                     [meStar, mhStar, eg, varshniEinstein, einf, f1f2,ag] = extractParameters(bandParams, material);
                    
                     if(strcmp(material,"Ge"))
                         mhStar= 0.34;% heavy hol from Cardona
                     end
                     
                    if(app.FitMCheckBox.Value)
                        mhStar = app.mhEditField.Value;
                        meStar = app.meEditField.Value;
                    end


                    % If there is PL data, we will use the Bose Einstein single
                    % oscillator equation
                    % The exponential modified gaussian fit method will be used, which is
                    % close to the first derivative method of extracting the band
                    % gap
                    
                    
                    % Load PL fitted data and use the bose einstein parameters for
                    % bandgap as a function of temperature
                    noPLData = app.NoPLDataCheckBox.Value;
                    DataToAnalyze2 = app.PLMATfilePath.Value;
                    if(~noPLData)
                        try
                            load(DataToAnalyze2, 'boseParameters')
                            varshniEinstein = @(T) boseEinstein(T, boseParameters(1,1), boseParameters(2,1), ...
                                boseParameters(3,1));
                            eg = boseParameters(1,1);
                            
                        catch
                            ErrorMessage = 'Problem trying to load PL MAT file: does file exist and contain "boseParameters?"';
                            app.ErrorReport.Value = ErrorMessage;
                            error(ErrorMessage);
                            
                        end
                    end


                    % Temperature dependent band gap in units of eV
                    egTemp = varshniEinstein(Tprobe) ./ 1000;
                    
                    % Conduction band edge energy will be the reference
                    % point
                    % 0K
                    conductionEdge = 0;
                    %conductionEdge = egTemp;
                    
                    
                    % Set the valence band edge having the bose einstein temperature
                    % dependence IT has a negative value
                    valenceEdge = conductionEdge - egTemp;
                    %valenceEdge = 0;


                    %get the active region thickness and convert it to nm
                    t = app.ActiveRegionThicknessEditField.Value * 1000;
                    n = app.CaplayerrefractiveindexatbandgapenergyEditField.Value;
                    
                    
                    [Nc, Nv, ni, G, phi] = ...
                        calculateKeyParameters(Tprobe, meStar, mhStar, egTemp, ag, n, t,einf );


                    intentionalDoping = app.IntentionaldopingCheckBox.Value;
                    
                    
                    if(intentionalDoping)
                        % calculate the fitted lifetimes
                        [totalLifetime, tauRad, tauSRH,tauSRH2, tauAug] = calculateLifetimes2(Tprobe, type,meStar,...
                            mhStar, egTemp, valenceEdge,conductionEdge, einf,...
                            Nc, Nv, ni, G, phi, blochOverlap, dopingDensity, defectLevel, defectDensity, defectLevel2, defectDensity2);
                        
                        
                    else
                        
                        % calculate the fitted lifetimes
                        [totalLifetime, tauRad, tauSRH, tauAug] = calculateLifetimes(Tprobe, type,meStar,...
                            mhStar, egTemp, valenceEdge,conductionEdge, einf,...
                            Nc, Nv, ni, G, phi, blochOverlap, dopingDensity, defectLevel, defectDensity);


                    end
                    
                    
                    %should we plot in a new Figure?
                    plotresults = app.ShowResultsCheckBox.Value;
                    lowestExcitation = app.LowestExcitationCheckBox.Value;
                    
                    
                    if(plotresults)
                        %PLot the lifetime components in a new figure
                        %(popup window)
                        
                        modelFigure = figure;
                        hold on
                        firstIndex = 1;
                        if(lowestExcitation)
                            firstIndex = length(Excitations);
                        end
                        
                        for exciteInd = firstIndex:length(Excitations)
                            if(size(t0Sigma_vTemp,2) > 0)
                                errorbar(Temperatures, t0_vTemp(adaptive_Ind,:,exciteInd),t0Sigma_vTemp(adaptive_Ind,:,exciteInd),...
                                    ColorWheel(exciteInd,:),'Displayname', string(Excitations(exciteInd)))
                            else
                                plot(Temperatures, t0_vTemp(adaptive_Ind,:,exciteInd),...
                                    ColorWheel(exciteInd,:),'Displayname', string(Excitations(exciteInd)))
                                
                            end
                            
                        end
                        plot(Tprobe,tauRad,ColorWheel2(1,:),'Displayname', 'Radiative')
                        plot(Tprobe,tauSRH,ColorWheel2(2,:), 'Displayname', 'SRH')
                        
                        if(intentionalDoping)
                            plot(Tprobe,tauSRH2,ColorWheel2(5,:), 'Displayname', 'SRH2',...
                                'MarkerSize', srh2MarkerSize)
                        end
                        
                        plot(Tprobe,tauAug,ColorWheel2(3,:), 'Displayname', 'Auger')
                        plot(Tprobe,totalLifetime, ColorWheel2(4,:),'Displayname','Total')
                        legend()
                        set(gca,'YScale', 'log',...
                            'box','on',...
                            'TickLength',[0.02,0.05],...
                            'LineWidth',1.5)
                        
                        hold off
                        ylim([0.3e-1, inf])
                        xlim([70,300])
                        xlabel(gca,'Temperature (K)')
                        ylabel(gca, 'Minority Carrier Lifetime (us)')
                        
                    else
                        
                        % Plot as a function of excitations
                        for exciteInd = 1:length(Excitations)
                            if(size(t0Sigma_vTemp,2) > 0)
                                errorbar(app.UIAxes, Temperatures, t0_vTemp(adaptive_Ind,:,exciteInd),t0Sigma_vTemp(adaptive_Ind,:,exciteInd),...
                                    ColorWheel(exciteInd,:),'Displayname', string(Excitations(exciteInd)))
                            else
                                plot(app.UIAxes, Temperatures, t0_vTemp(adaptive_Ind,:,exciteInd),...
                                    ColorWheel(exciteInd,:),'Displayname', string(Excitations(exciteInd)))
                                
                            end
                            hold(app.UIAxes,'on')
                        end
                        
                        %Plot the lifetime components in the app window
                        plot(app.UIAxes,Tprobe,tauRad,ColorWheel2(1,:),'Displayname', 'Radiative')
                        plot(app.UIAxes,Tprobe,tauSRH,ColorWheel2(2,:), 'Displayname', 'SRH')
                        if(intentionalDoping)
                            plot(app.UIAxes,Tprobe,tauSRH2,ColorWheel2(5,:), 'Displayname', 'SRH2',...
                                'MarkerSize', srh2MarkerSize)
                        end
                        plot(app.UIAxes,Tprobe,tauAug,ColorWheel2(3,:), 'Displayname', 'Auger')
                        plot(app.UIAxes,Tprobe,totalLifetime, ColorWheel2(4,:),'Displayname','Total')
                        legend(app.UIAxes)
                        set(app.UIAxes, 'YScale', 'log')
                        hold(app.UIAxes, 'off')
                        ylim(app.UIAxes,[1e-2, 1e3])
                        xticks(app.UIAxes,'auto')
                    end
                    
                    
                    % Go back to waiting mode
                    simulateData = 0;
                    
                    %Restart while Breaker
                    whileBreaker = 0;
                    
                    
                    %It will essentially do the same thing but first fit
                    %and then plot
                elseif(fitData)
                    % re initialize the data, because user may go straight
                    % to fitting routine
                    Carrier_Fitting
                    material = '';
                    bandParams = [];
                    materialDopingType = '';
                    dopingDensity = nan;
                    defectDensity = nan;
                    defectLevel  = nan;
                    DataToAnalyze2 = '';
                    Nc  = nan;
                    Nv  = nan;
                    ni  = nan;
                    G   = nan;
                    phi = nan;
                    fitDownTo = NaN;
                    bestInputs = [];
                    %Dont let the user fit without pressing start and
                    %simulated
                    %materialsDataBase = readtable('BandParameters.csv');
                    material = app.MaterialDropDown.Value;
                    
                    %%%% THE RANGE OF THE FITTING ALGORITHM GUESSING
                    lowerN0 = app.Lowern0LimitEditField.Value;
                    upperN0 = app.Uppern0limitEditField.Value;
                    
                    lowerETeV = -app.UpperDefectLevelLimitEditField.Value/1000;
                    upperETeV = -app.LowerDefectLevelLimitEditField.Value/1000;
                    lowerDefectdens = app.LowerDefectDensityLimitEditField.Value;
                    upperDefectdens = app.UpperDefectDensityLimitEditField.Value;
                    
                    lowerF1F2 = app.LowerF1F2LimitEditField.Value;
                    upperF1F2 = app.UpperF1F2LimitEditField.Value;
                    rngSeed = app.RNGseedEditField.Value;
                    numGuesses = app.NumberofGuessesEditField.Value;
                    
                    
                    %row1     =  Band gap in eV (0 K) perhaps
                    %row2     =  electron effective mass in units of free electron mass
                    %row3     =  gamma1 inverse effective mass parameter 1
                    %row4     =  gamma2 inverse effective mass parameter 2
                    %row5     =  gamma3 inverse effective mass parameter 3
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%%%% READ EVERYTHING IN THE APP AND PREPARE FOR FIT %%%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    switch(material)
                        case 'InAs'
                            bandParams = materialsDataBase.InAs;
                        case 'GaAs'
                            bandParams = materialsDataBase.GaAs;
                        case 'InSb'
                            bandParams = materialsDataBase.InSb;
                        case 'InAsSb'
                            % use InAs band parameters
                            bandParams = materialsDataBase.InAs;
                        case 'Ge'
                            bandParams = materialsDataBase.Ge;
                        otherwise
                            bandParams = materials.DataBase.InAs;
                    end
                    
                    type = 'i';
                    materialDopingType = app.DopingDropDown.Value;
                    % Check the doping Type
                    switch(materialDopingType)
                        case('Intrinsic')
                            type = 'i';
                        case('p-type')
                            type ='p';
                        case('n-type')
                            type = 'n';
                    end


                    % in units of 1e15 per cm^3 we will convert to per cm^3
                    % in the functions (fitting a very large number seems
                    % to cause it to be insensitive)
                    dopingDensity = app.DopingDensityEditField.Value;
                    
                    % in units of per m
                    defectDensity = app.DefectDensity1EditField.Value;
                    
                    % Units of meV, so change to eV The negative shows that
                    % the conduction band edge is the zero energy level
                    defectLevel = -app.DefectLevel1EditField.Value/1000;
                    
                    %defectLevel = app.DefectLevel1EditField.Value/1000;
                    
                    
                    % For the case of intentional doping
                    defectDensity2 = app.DefectDensity2EditField.Value;
                    %defectLevel2 = -app.DefectLevel2EditField.Value/1000;
                    defectLevel2 = app.DefectLevel2EditField.Value/1000;
                    
                    
                    intentionalDoping = app.IntentionaldopingCheckBox.Value;
                    
                    % extract the band parameters and use the Varshni equation
                    % with given parameters from the Vurgaftman review article
                    [meStar, mhStar, eg, varshniEinstein, einf, f1f2, ag] = extractParameters(bandParams, material);
                    
                     if(strcmp(material,"Ge"))
                         mhStar= 0.34;% heavy hole from Cardona
                     end
                        
                    %Are we going to be fitting the effective masses? if so,
                    %use the user defined mh and me as the initial guess
                    if app.FitMCheckBox.Value
                        mhStar = app.mhEditField.Value;
                        meStar = app.meEditField.Value;
                    end
                    
                    
                    % If there is PL data, we will use the Bose Einstein single
                    % oscillator equation
                    % The first derivative method of extracting the band
                    % gap through EMG will be used ----SEE the PL_Analyzer app in PL_AnalyzerCurveFittingToolboxV2
                    noPLData = app.NoPLDataCheckBox.Value;
                    DataToAnalyze2 = app.PLMATfilePath.Value;
                    
                    if(~noPLData)
                        try
                            load(DataToAnalyze2, 'boseParameters')
                            varshniEinstein = @(T) boseEinstein(T, boseParameters(1,1), boseParameters(2,1), ...
                                boseParameters(3,1));
                            eg = boseParameters(1,1);
                            
                        catch
                            ErrorMessage = 'Problem trying to load PL MAT file: does file exist and contain "boseParameters?"';
                            app.ErrorReport.Value = ErrorMessage;
                            error(ErrorMessage);
                            
                        end
                    end


                    % Temperature dependent band gap in units of eV
                    egTemp = varshniEinstein(Tprobe) ./ 1000;
                    
                    % Conduction band edge energy will be the reference of
                    % 0 eV
                    conductionEdge = 0;
                    %conductionEdge = egTemp;
                    
                    % Set the valence band edge having the bose einstein temperature
                    % dependence
                    valenceEdge =  conductionEdge - egTemp;
                    %valenceEdge = 0;
                    
                    %get the active region thickness and convert it to nm
                    t = app.ActiveRegionThicknessEditField.Value * 1000;
                    n = app.CaplayerrefractiveindexatbandgapenergyEditField.Value;


                    %Are we going to fit to the temperatures above a given
                    %value?
                    lowTAbove = app.FittoTaboveCheckBox.Value;
                    if lowTAbove
                        fitDownTo = app.LowTlimitEditField.Value;
                        aboveTIndices = Temperatures>= fitDownTo;
                        xinFit = Temperatures(aboveTIndices);
                        yinFit = t0_vTemp(:,aboveTIndices,:);
                        yerrFit = t0Sigma_vTemp(:, aboveTIndices,:);
                    else
                        fitDownTo = 0;
                        aboveTIndices = Temperatures >= fitDownTo;
                        xinFit = Temperatures(aboveTIndices);
                        yinFit = t0_vTemp(:,aboveTIndices,:);
                        yerrFit = t0Sigma_vTemp(:,aboveTIndices,:);
                    end
                    
                    %%%%Determine what was selected in the dropdown
                    %%%%And use the drop
                    dropDownValue = app.ExcitationDropDown.Value;
                    
                    %%Check whether the chosen value is "All" or "Average"
                    if(strcmp(dropDownValue,'All'))
                        %%Concatenate the data
                        yfit = horzcat(yinFit(adaptive_Ind, :));
                        yerr = horzcat(yerrFit(adaptive_Ind,:));
                        
                        
                    elseif(strcmp(dropDownValue,'Average'))
                        %%Take an average of the data
                        yfit = mean(yinFit(adaptive_Ind,:,:),3); 
                        yerr = std(yinFit(adaptive_Ind,:,:),0,3);
       
                    else
                        %determine which angle to look at
                        angle = str2double(dropDownValue);
                        index = angle == Excitations;
                        yfit = yinFit(adaptive_Ind, :,index);
                        yerr = yerrFit(adaptive_Ind, :,index);
                       
                    end
                    repNumber = size(yfit,2)/size(xinFit,2);


                    Temperaturesconcat = repmat(xinFit, 1, repNumber); %repeat the temperature
                    if(isnan(yerr))
                        yerr = [];
                    end
                    weightedData = app.WeightedDataCheckBox.Value;
                    
                    if(~weightedData)
                        yerr = [];
                    end
                    
                    
                    % weights = [];
                    
                    %Assume that we have some good error bars by
                    %remeasuring
                    %weights = 1./yerr;
                    
                    
                    %The weights are 1/yerr.^2
                    weights = 1./yerr.^2;


                    % Take the user input auger overlap value which is 0.15
                    % (typically)
                    blochOverlap = app.BlochoverlapEditField.Value;
                    % Do i fit the block overlap parameter?
                    augeroverlapfit = app.FitBlochCheckBox.Value;
                    %do we fix SRH1 defect level and defect density
                    fixDefect1 = app.FixSRH1CheckBox.Value;
                    %do we Fit electron/hole effective masses
                    fitEff1 = app.FitMCheckBox.Value;
                    indexBest = NaN;
                    
                    fixSRHDefectLevel = app.FixDefectLevel1CheckBox.Value;
                    
                    egTemp2 = varshniEinstein(Temperaturesconcat)./ 1000;
                    valenceEdge2 = conductionEdge - egTemp2;
                    %valenceEdge2 = 0;
                    
                    conductionEdge2 = 0;
                    %conductionEdge2  = egTemp2;
                    
                    [Nc2, Nv2, ni2, G2, phi] = ...
                        calculateKeyParameters(Temperaturesconcat, meStar, mhStar,  egTemp2, ag, n, t, einf);
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%%%% FINISHED READING APP, BEGIN FITTING %%%%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if (fitEff1) %Fit the effective masses
                        if(intentionalDoping) %do we fit effective masses and doping
                            [fobjects, gofs, outputs, bestInputs, indexBest] = fitLifetimes2(app,Temperaturesconcat, yfit,weights,augeroverlapfit,fixDefect1,fixSRHDefectLevel,...
                                type, egTemp2, valenceEdge2, conductionEdge2, einf,...
                                phi,meStar,mhStar, blochOverlap, dopingDensity, defectLevel, defectDensity,...
                                lowerN0, upperN0,lowerETeV, upperETeV, lowerDefectdens, upperDefectdens,...
                                lowerF1F2,upperF1F2, rngSeed, numGuesses,...
                                defectLevel2, defectDensity2);
                            
                            %Find the best results and show that!
                            %[~,indexBest] = min([gofs.rmse]);
                            
                            defectLevel2 = fobjects{indexBest}.o;
                            app.DefectLevel2EditField.Value = -defectLevel2*1000;
                            
                            defectDensity2 = fobjects{indexBest}.p;
                            app.DefectDensity2EditField.Value = defectDensity2;
                            
                        else % no doping
                            
                            [fobjects, gofs, outputs, bestInputs,indexBest] = fitLifetimes2(app,Temperaturesconcat, yfit,weights, augeroverlapfit,fixDefect1,fixSRHDefectLevel,...
                                type,egTemp2, valenceEdge2, conductionEdge2, einf,...
                                phi,meStar, mhStar, blochOverlap, dopingDensity, defectLevel, defectDensity,...
                                lowerN0, upperN0,lowerETeV, upperETeV, lowerDefectdens, upperDefectdens,...
                                lowerF1F2,upperF1F2, rngSeed, numGuesses);
                            
                            %Find the best results and show that!
                            %[~,indexBest] = min([gofs.rmse]);
                        end
                        
                        
                        %{
                     %weight the data by itself
                      weights = diag(t0_vTemp(1,:,end));
                        %}
                        
                        %Extract the effective masses from the best fit
                        meStar = fobjects{indexBest}.g;
                        
                        
                        mhStar = fobjects{indexBest}.h;


                        %EXtract the F1F2, doping, defect level, and defect
                        % density from the best fit
                        blochOverlap = fobjects{indexBest}.k;
                        
                        dopingDensity = fobjects{indexBest}.l;
                        defectLevel = fobjects{indexBest}.m;
                        defectDensity = fobjects{indexBest}.n;


                        %Set the effective mass to the best fit value
                        app.meEditField.Value = meStar;
                        app.mhEditField.Value = mhStar;


                    else % Don't fit the effective masses
                        if(intentionalDoping)
                            %This adds a second SRH level which follows Olson's
                            %convention
                            %ref: B.V. Olson et al APL vol 103 p. 052106
                            %(2013)
                            %http://dx.doi.org/10.1063/1.4817400
                            %Identification of dominant recombination
                            %mechanisms in narrow-bandgap InAs/InAsSb
                            %type-II superlattices and InAsSb alloys
                            
                            [fobjects, gofs, outputs,bestInputs,indexBest] = ...
                                fitLifetimes(app,Temperaturesconcat, yfit, weights, augeroverlapfit,fixDefect1,fixSRHDefectLevel,...
                                type, meStar, mhStar, egTemp2, valenceEdge2, conductionEdge2, einf,...
                                Nc2, Nv2, ni2, G2, phi, blochOverlap, dopingDensity, defectLevel, defectDensity, lowerN0, upperN0,...
                                lowerETeV, upperETeV, lowerDefectdens, upperDefectdens, lowerF1F2, upperF1F2,...
                                rngSeed, numGuesses, defectLevel2, defectDensity2);
                            
                            %Find the best results and show that!
                            defectLevel2 = fobjects{indexBest}.s;
                            app.DefectLevel2EditField.Value = -defectLevel2*1000;
                            
                            defectDensity2 = fobjects{indexBest}.t;
                            app.DefectDensity2EditField.Value = defectDensity2;
                        else
                            %Just do a single SRH fit
                            [fobjects, gofs, outputs,bestInputs,indexBest] = ...
                                fitLifetimes(app,Temperaturesconcat, yfit,weights,augeroverlapfit,fixDefect1,fixSRHDefectLevel,...
                                type,meStar,mhStar, egTemp2, valenceEdge2, conductionEdge2, einf,...
                                Nc2, Nv2, ni2, G2, phi, blochOverlap, dopingDensity, defectLevel, defectDensity,lowerN0, upperN0,...
                                lowerETeV, upperETeV, lowerDefectdens, upperDefectdens,lowerF1F2,upperF1F2,...
                                rngSeed, numGuesses);


                        end
                        
                        %Show what the user input best fits are
                        dopingDensity = fobjects{indexBest}.p;
                        
                        defectLevel = fobjects{indexBest}.q;
                        
                        defectDensity = fobjects{indexBest}.r;
                        
                        blochOverlap = fobjects{indexBest}.o;


                    end
                    
                    app.DopingDensityEditField.Value = dopingDensity;
                    %app.DefectLevel1EditField.Value = -defectLevel * 1000;
                    app.DefectLevel1EditField.Value = -defectLevel * 1000;

                    app.DefectDensity1EditField.Value = defectDensity;
                    app.BlochoverlapEditField.Value = blochOverlap;
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%%%% Now use the best fit parameters to show the result %%%%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    
                    [Nc, Nv, ni, G, phi] = ...
                        calculateKeyParameters(Tprobe, meStar, mhStar, egTemp, ag, n, t, einf);               
                          
                    if (intentionalDoping)
                        [totalLifetime, tauRad, tauSRH, tauSRH2, tauAug] = calculateLifetimes2(Tprobe, type,meStar,...
                            mhStar, egTemp, valenceEdge, conductionEdge, einf,Nc, Nv, ni, G, phi,...
                            blochOverlap, dopingDensity, defectLevel, defectDensity, defectLevel2, defectDensity2);
                        
                    else
                        % calculate the lifetimes
                        [totalLifetime, tauRad, tauSRH, tauAug] = calculateLifetimes(Tprobe, type,meStar,...
                            mhStar, egTemp, valenceEdge, conductionEdge, einf,Nc, Nv, ni, G, phi,...
                            blochOverlap, dopingDensity, defectLevel, defectDensity);
                        
                        
                    end
                    
                    
                    % Plot as a function of excitations
                    %{
                    for exciteInd = 1:length(Excitations)
                        plot(app.UIAxes,Temperatures, t0_vTemp(adaptive_Ind,:,exciteInd), ColorWheel(exciteInd,:),...
                            'Displayname', string(Excitations(exciteInd)))
                        hold(app.UIAxes,'on')
                    end
                    %}
                    errorbar(app.UIAxes,Temperaturesconcat, yfit,yerr, 'o',...
                            'Displayname', dropDownValue)
                        hold(app.UIAxes,'on')
                    
                    plot(app.UIAxes,Tprobe,tauRad,ColorWheel2(1,:),'Displayname', 'Radiative')
                    
                    plot(app.UIAxes,Tprobe,tauSRH,ColorWheel2(2,:), 'Displayname', 'SRH')
                    if(intentionalDoping)
                        plot(app.UIAxes,Tprobe,tauSRH2,ColorWheel2(5,:), 'Displayname', 'SRH2',...
                            'MarkerSize', srh2MarkerSize)
                        
                    end
                    
                    plot(app.UIAxes,Tprobe,tauAug,ColorWheel2(3,:), 'Displayname', 'Auger')
                    plot(app.UIAxes,Tprobe,totalLifetime, ColorWheel2(4,:),'Displayname','Total')
                    
                    legend(app.UIAxes)
                    set(app.UIAxes, 'YScale', 'log')
                    
                    matlabrmse = gofs(indexBest).rmse;
                    title(app.UIAxes,strcat('Best fit (',num2str(indexBest),'/',num2str(numGuesses+1),'); rmse = ', num2str(matlabrmse,3)));
                    
                    hold(app.UIAxes, 'off')
                    ylim(app.UIAxes,[1e-2, 1e3])
                    
                    Carrier_Activate;


                    %Reset the fitting routine
                    fitData = 0;
                    
                    %Reset the wait time to exit/abort
                    whileBreaker = 0;
                    
                    
                elseif(saveResults)
                    createTxtFiles = app.createtxtFilesCheckBox.Value; %create txt file to plot in other software
                    %such as origin, kaliedagraph etc
                    fileSuffix = app.UserOutputFileSuffix.Value; %append to file
                    
                    Carrier_Writing;
                    %save results to text files
                    %{
                    coeffss = coeffvalues(fobjects{indexBest});
                    coeffNamess = coeffnames(fobjects{indexBest});
                    jacobianBest = outputs(indexBest).Jacobian;
                    rmseBest = gofs(indexBest).rmse;
                    tempSize = size(Temperatures,2);
                    [sigmap, correlation] =  calculateErrors(tempSize,jacobianBest, rmseBest);
                    
                    %}
                    if(createTxtFiles)
                        
                        intentionalDoping =app.IntentionaldopingCheckBox.Value;
                        try
                            saveTable = table(Tprobe',tauRad,tauSRH,tauAug,totalLifetime); %Do we have simulated data to save
                        catch
                            errorMessage = 'Problem Creating a table to save lifetime components. Try to simulate data';
                            app.ErrorReport.Value = errorMessage;
                            saveResults = 0;
                            continue;
                            
                        end
                        
                        %Create a temperatures vs lifetime data save file
                        t0Table = zeros(size(Temperatures,2) + 1, size(t0_vTemp,3) + 1);
                        t0Table(2:end,1) = Temperatures;
                        t0Table(1,2:end) = Excitations;
                        t0Table(2:end, 2:end) = t0_vTemp(1,:,:);
                        lifeTimeDataName = string(SampleName) + fileSuffix + '_t0_vTemp.csv';
                        
                        dlmwrite(lifeTimeDataName,t0Table);
                        
                        
                        if(intentionalDoping) %Include 2 SRH terms and recreate table
                            saveTable = table(Tprobe',tauRad,tauSRH,tauSRH2,tauAug,totalLifetime);
                            
                            %Save the temperature, and lifetime components for
                            %plotting on other software, such as origin
                            saveTable.Properties.VariableNames = {'Temp_K', 'tauRad_us', 'tauSRH_us','tauSRH2_us' 'tauAug_us','Total_us'};
                            filename = string(SampleName) + fileSuffix +"_LifetimeComponents.csv";
                            
                            writetable(saveTable, filename);
                            
                            %Input parameters to put in a table to save, which
                            %will be the best fit results from the user's
                            %initial guess
                            
                            saveTable2 = table(dopingDensity,app.DefectLevel1EditField.Value, defectDensity,...
                                app.DefectLevel2EditField.Value, app.DefectDensity2EditField.Value,...
                                app.BlochoverlapEditField.Value);
                            
                            
                            saveTable2.Properties.VariableNames = {'DopingDensity_1e15_cm3', 'DefectLevel_meV', 'DefectDensity_m1',...
                                'DefectLevel2_meV', 'DefectDensity2_m1','F1F2'};
                            filename2 = string(SampleName) + fileSuffix + '_InputParameters.csv';
                            
                            writetable(saveTable2,filename2);


                        else %Only save a single SRH parameter
                            saveTable.Properties.VariableNames = {'Temp_K', 'tauRad_us', 'tauSRH_us', 'tauAug_us','Total_us'};
                            filename = string(SampleName) + fileSuffix + "_LifetimeComponents.csv";
                            writetable(saveTable, filename);
                            
                            
                            saveTable2 = table(dopingDensity,app.DefectLevel1EditField.Value, defectDensity,...
                                app.BlochoverlapEditField.Value);
                            saveTable2.Properties.VariableNames = {'DopingDensity_1e15_cm3', 'DefectLevel_meV', 'defectDensity_m1',...
                                'F1F2'};


                            filename2 = string(SampleName) + fileSuffix + '_InputParameters.csv';


                            writetable(saveTable2,filename2);
                            
                            
                        end
                        
                        
                        if(exist('bestInputs','var'))
                            filename3 = string(SampleName) + fileSuffix + '_InputParametersAndErrors.csv';
                            writetable(bestInputs, filename3,'WriteRowNames',true);
                        end


                    end
                    %Also Save results to a matlab file
                    
                    filenameMAT = string(SampleName) + fileSuffix + '_CarrierAnalysis';
                    if(intentionalDoping)
                        
                        if (exist('fobjects','var') &&exist('outputs','var')) %Did we ever do a fitting routine
                            sigmap = bestInputs.Errors;
                            save(filenameMAT, 'fobjects', 'gofs', 'outputs', 'Temperatures' , 't0_vTemp','phi', 'totalLifetime',...
                                'varshniEinstein','tauAug', 'tauSRH','tauSRH2','tauRad','Tprobe','SampleName','Excitations','indexBest',...
                                'sigmap','bestInputs','fitDownTo', 't0Sigma_vTemp','yfit','yerr', 'Temperaturesconcat','dropDownValue');
                        else
                            save(filenameMAT, 'Temperatures' , 't0_vTemp','phi', 'totalLifetime',...
                                'varshniEinstein','tauAug', 'tauSRH','tauSRH2','tauRad','Tprobe','SampleName','Excitations','singleExcitation', 't0Sigma_vTemp');
                        end
                        
                        
                    else
                        
                        if (exist('fobjects','var') &&exist('outputs','var')) %Did we ever do a fitting routine
                            sigmap = bestInputs.Errors;
                            save(filenameMAT, 'fobjects', 'gofs', 'outputs', 'Temperatures' , 't0_vTemp','phi', 'totalLifetime',...
                                'varshniEinstein','tauAug', 'tauSRH','tauRad','Tprobe','SampleName','Excitations','indexBest',...
                                'sigmap','bestInputs','fitDownTo', 't0Sigma_vTemp', 'yfit','yerr', 'Temperaturesconcat','dropDownValue');
                        else
                            save(filenameMAT, 'Temperatures' , 't0_vTemp','phi', 'totalLifetime',...
                                'varshniEinstein','tauAug', 'tauSRH','tauRad','Tprobe','SampleName','Excitations','singleExcitation', 't0Sigma_vTemp');
                        end
                    end


                    Carrier_Activate;
                    %Reset the save routine and leave app in wait mode
                    saveResults = 0;


                    whileBreaker = 0;
                    
                    
                elseif(abortNow || (whileBreaker > whileIterationLimit))
                    % Operations aborted, reset tool
                    LT_HardReset
                    
                    % Reset clears the decay plot, also clear the temperature dependence plot
                    scatter( app.UIAxes , 1 , 1 , 'x' )
                    abortNow = 0;
                    % Abort if user presses button or if whileIterationLimit time-out reached
                    return


                end
                
            end


        end

        % Button pushed function: AbortButton
        function abortButtonPushed(app, event)
            
            % Define global variable AbortNow = 1 to enable analysis to repeat
            global abortNow
            abortNow = 1;
            
            
        end

        % Button pushed function: SimulateButton
        function simulateButtonPushed(app, event)
            
            global simulateData
            simulateData = 1;
            
        end

        % Button pushed function: FitButton
        function fitButtonPushed(app, event)
            global fitData
            fitData = 1;
        end

        % Button pushed function: SaveButton
        function saveButtonPushed(app, event)
            global saveResults
            saveResults = 1;
        
        end

        % Value changed function: IntentionaldopingCheckBox
        function Doping(app, event)
            value = app.IntentionaldopingCheckBox.Value;
            if(value)
                app.DefectDensity2EditField.Editable = 'on';
                app.DefectDensity2EditField.Enable = 'on';
                
                app.DefectLevel2EditField.Editable = 'on';
                app.DefectLevel2EditField.Enable ='on';
                
                app.FixSRH1CheckBox.Value = true;
                app.DefectDensity1EditField.Editable = 'off';
                app.DefectLevel1EditField.Editable = 'off';
                app.FixSRH1CheckBox.Enable ='on';
                
            else
                % Make the editfield seem like it won't be used
                app.DefectDensity2EditField.Editable = 'off';
                app.DefectDensity2EditField.Enable = 'off';
                
                %Make the editfield seem like it won't be used
                app.DefectLevel2EditField.Editable = 'off';
                app.DefectLevel2EditField.Enable = 'off';
                
                % The first defect Level will not be used to
                app.FixSRH1CheckBox.Value = false;
                app.DefectDensity1EditField.Editable = 'on';
                app.DefectLevel1EditField.Editable = 'on';
                app.FixSRH1CheckBox.Enable = 'off';


            end
        end

        % Value changed function: FixSRH1CheckBox
        function FixDefect1(app, event)
            value = app.FixSRH1CheckBox.Value;
            if(value)
                app.DefectDensity1EditField.Editable = 'off';
                app.DefectLevel1EditField.Editable = 'off';
            else
                app.DefectDensity1EditField.Editable = 'on';
                app.DefectLevel1EditField.Editable = 'on';
            end
        end

        % Value changed function: LowestExcitationCheckBox
        function lowExcitationChecked(app, event)
            value = app.LowestExcitationCheckBox.Value;
            if(value)
                app.UserOutputFileSuffix.Value = "_LowestExcitation";
                app.ExcitationDropDown.Value = app.ExcitationDropDown.Items(end-1);
                app.ExcitationDropDown.Editable = 'off';
                app.ExcitationDropDown.Enable = 'off';
            else
                app.ExcitationDropDown.Editable = 'on';
                app.ExcitationDropDown.Enable = 'on';
                
            end


        end

        % Value changed function: ExcitationDropDown
        function ExcitationChosen(app, event)
            value = app.ExcitationDropDown.Value;
            if(strcmp(value,'All'))
                app.UserOutputFileSuffix.Value = '_AllExcitations';
           
            elseif(strcmp(value,'Average'))
                app.UserOutputFileSuffix.Value = '_AverageExcitations';
            else
                app.UserOutputFileSuffix.Value = sprintf('_SingleExcitation(%s)', value);
                
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DataFittingStatusEditFieldLabel = uilabel(app.UIFigure);
            app.DataFittingStatusEditFieldLabel.Text = 'Data Fitting Status:';
            app.ToolStatus = uieditfield(app.UIFigure, 'text');
            app.ToolStatus.Editable = 'off';
            app.ToolStatus.Value = 'INACTIVE';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature Dependence')
            xlabel(app.UIAxes, 'Temperature (K)')
            ylabel(app.UIAxes, 'Characteristic Slope (us)')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.InputparametersandusercontrolPanel = uipanel(app.UIFigure);
            app.InputparametersandusercontrolPanel.Title = 'Input parameters and user control';
            app.MaterialDropDownLabel = uilabel(app.InputparametersandusercontrolPanel);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.InputparametersandusercontrolPanel);
            app.MaterialDropDown.Items = {'InAs', 'GaAs', 'InAsSb', 'InSb', 'Ge'};
            app.MaterialDropDown.Value = 'InAs';
            app.IntentionaldopingCheckBox = uicheckbox(app.InputparametersandusercontrolPanel);
            app.IntentionaldopingCheckBox.ValueChangedFcn = createCallbackFcn(app, @Doping, true);
            app.IntentionaldopingCheckBox.Text = 'Intentional doping?';
            app.DopingTypeDropDownLabel = uilabel(app.InputparametersandusercontrolPanel);
            app.DopingTypeDropDownLabel.Text = 'Doping Type';
            app.DopingDropDown = uidropdown(app.InputparametersandusercontrolPanel);
            app.DopingDropDown.Items = {'Intrinsic', 'n-type', 'p-type', 'Option 4'};
            app.DopingDropDown.Value = 'n-type';
            app.DopingDensityEditFieldLabel = uilabel(app.InputparametersandusercontrolPanel);
            app.DopingDensityEditFieldLabel.Text = 'Doping Density';
            app.DopingDensityEditField = uieditfield(app.InputparametersandusercontrolPanel, 'numeric');
            app.DopingDensityEditField.Value = 1.7;
            app.X1e15cm3Label = uilabel(app.InputparametersandusercontrolPanel);
            app.X1e15cm3Label.Text = 'X1e15 cm(-3)';
            app.DefectLevel1EditFieldLabel = uilabel(app.InputparametersandusercontrolPanel);
            app.DefectLevel1EditFieldLabel.Text = 'Defect Level 1';
            app.DefectLevel1EditField = uieditfield(app.InputparametersandusercontrolPanel, 'numeric');
            app.DefectLevel1EditField.Value = 67;
            app.meVLabel_7 = uilabel(app.InputparametersandusercontrolPanel);
            app.meVLabel_7.Text = 'meV';
            app.FixSRH1CheckBox = uicheckbox(app.InputparametersandusercontrolPanel);
            app.FixSRH1CheckBox.ValueChangedFcn = createCallbackFcn(app, @FixDefect1, true);
            app.FixSRH1CheckBox.Enable = 'off';
            app.FixSRH1CheckBox.Text = 'Fix SRH1?';
            app.DefectDensity1EditFieldLabel = uilabel(app.InputparametersandusercontrolPanel);
            app.DefectDensity1EditFieldLabel.Text = 'Defect Density 1';
            app.DefectDensity1EditField = uieditfield(app.InputparametersandusercontrolPanel, 'numeric');
            app.DefectDensity1EditField.Value = 0.7;
            app.m1Label_7 = uilabel(app.InputparametersandusercontrolPanel);
            app.m1Label_7.Text = 'm(-1)';
            app.DefectLevel2EditFieldLabel = uilabel(app.InputparametersandusercontrolPanel);
            app.DefectLevel2EditFieldLabel.Text = 'Defect Level 2';
            app.DefectLevel2EditField = uieditfield(app.InputparametersandusercontrolPanel, 'numeric');
            app.DefectLevel2EditField.Editable = 'off';
            app.DefectLevel2EditField.Enable = 'off';
            app.DefectLevel2EditField.Value = 70;
            app.meVLabel_8 = uilabel(app.InputparametersandusercontrolPanel);
            app.meVLabel_8.Text = 'meV';
            app.DefectDensity2EditFieldLabel = uilabel(app.InputparametersandusercontrolPanel);
            app.DefectDensity2EditFieldLabel.Text = 'Defect Density 2';
            app.DefectDensity2EditField = uieditfield(app.InputparametersandusercontrolPanel, 'numeric');
            app.DefectDensity2EditField.Editable = 'off';
            app.DefectDensity2EditField.Enable = 'off';
            app.DefectDensity2EditField.Value = 50;
            app.m1Label_8 = uilabel(app.InputparametersandusercontrolPanel);
            app.m1Label_8.Text = 'm(-1)';
            app.BlochoverlapEditFieldLabel = uilabel(app.InputparametersandusercontrolPanel);
            app.BlochoverlapEditFieldLabel.Text = 'Bloch overlap';
            app.BlochoverlapEditField = uieditfield(app.InputparametersandusercontrolPanel, 'numeric');
            app.BlochoverlapEditField.Value = 0.15;
            app.FitBlochCheckBox = uicheckbox(app.InputparametersandusercontrolPanel);
            app.FitBlochCheckBox.Text = 'Fit Bloch?';
            app.ActiveRegionThicknessEditFieldLabel = uilabel(app.InputparametersandusercontrolPanel);
            app.ActiveRegionThicknessEditFieldLabel.Text = 'Active Region Thickness';
            app.ActiveRegionThicknessEditField = uieditfield(app.InputparametersandusercontrolPanel, 'numeric');
            app.ActiveRegionThicknessEditField.Value = 2;
            app.umLabel = uilabel(app.InputparametersandusercontrolPanel);
            app.umLabel.Text = '(um)';
            app.CaplayerrefractiveindexatbandgapenergyEditFieldLabel = uilabel(app.InputparametersandusercontrolPanel);
            app.CaplayerrefractiveindexatbandgapenergyEditFieldLabel.Text = 'Cap layer refractive index at bandgap energy';
            app.CaplayerrefractiveindexatbandgapenergyEditField = uieditfield(app.InputparametersandusercontrolPanel, 'numeric');
            app.CaplayerrefractiveindexatbandgapenergyEditField.Value = 3.7;
            app.FitMCheckBox = uicheckbox(app.InputparametersandusercontrolPanel);
            app.FitMCheckBox.Text = 'Fit M*?';
            app.mhEditFieldLabel = uilabel(app.InputparametersandusercontrolPanel);
            app.mhEditFieldLabel.Text = 'mh';
            app.mhEditField = uieditfield(app.InputparametersandusercontrolPanel, 'numeric');
            app.mhEditField.Value = 0.3333;
            app.meEditFieldLabel = uilabel(app.InputparametersandusercontrolPanel);
            app.meEditFieldLabel.Text = 'me';
            app.meEditField = uieditfield(app.InputparametersandusercontrolPanel, 'numeric');
            app.meEditField.Value = 0.026;
            app.m0Label = uilabel(app.InputparametersandusercontrolPanel);
            app.m0Label.Text = '(m0)';
            app.FixDefectLevel1CheckBox = uicheckbox(app.InputparametersandusercontrolPanel);
            app.FixDefectLevel1CheckBox.Text = 'Fix?';
            app.DataFittingPanel = uipanel(app.UIFigure);
            app.DataFittingPanel.Title = 'Data Fitting';
            app.TRPLFittedresultsMATfileLabel = uilabel(app.DataFittingPanel);
            app.TRPLFittedresultsMATfileLabel.Text = 'TRPL Fitted results MAT file:';
            app.TRPLMATfilePath = uieditfield(app.DataFittingPanel, 'text');
            app.TRPLMATfilePath.Value = 'GN1882_FittedResults.mat';
            app.PLFittedresultsMATfileEditFieldLabel = uilabel(app.DataFittingPanel);
            app.PLFittedresultsMATfileEditFieldLabel.Text = 'PL Fitted results MAT file:';
            app.PLMATfilePath = uieditfield(app.DataFittingPanel, 'text');
            app.PLMATfilePath.Value = 'GN1882ESTIMATE_FittedResultsPL.mat';
            app.StartButton = uibutton(app.DataFittingPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SimulateButton = uibutton(app.DataFittingPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @simulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.FitButton = uibutton(app.DataFittingPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @fitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.SaveButton = uibutton(app.DataFittingPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AbortButton = uibutton(app.DataFittingPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @abortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.NoPLDataCheckBox = uicheckbox(app.DataFittingPanel);
            app.NoPLDataCheckBox.Text = 'No PL Data?';
            app.OutputfilesuffixLabel = uilabel(app.DataFittingPanel);
            app.OutputfilesuffixLabel.Text = 'Output file suffix:';
            app.UserOutputFileSuffix = uieditfield(app.DataFittingPanel, 'text');
            app.UserOutputFileSuffix.Value = '_AllExcitations';
            app.ShowResultsCheckBox = uicheckbox(app.DataFittingPanel);
            app.ShowResultsCheckBox.Text = 'Show Results?';
            app.createtxtFilesCheckBox = uicheckbox(app.DataFittingPanel);
            app.createtxtFilesCheckBox.Text = 'Create .txt Files on save?';
            app.LowestExcitationCheckBox = uicheckbox(app.DataFittingPanel);
            app.LowestExcitationCheckBox.ValueChangedFcn = createCallbackFcn(app, @lowExcitationChecked, true);
            app.LowestExcitationCheckBox.Text = 'Lowest Excitation?';
            app.ExcitationDropDownLabel = uilabel(app.DataFittingPanel);
            app.ExcitationDropDownLabel.Text = 'Excitation';
            app.ExcitationDropDown = uidropdown(app.DataFittingPanel);
            app.ExcitationDropDown.Items = {'All', 'Option 2', 'Option 3', 'Option 4'};
            app.ExcitationDropDown.ValueChangedFcn = createCallbackFcn(app, @ExcitationChosen, true);
            app.ExcitationDropDown.Value = 'All';
            app.ErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorEditFieldLabel.Text = 'Error:';
            app.ErrorReport = uieditfield(app.UIFigure, 'text');
            app.ErrorReport.Editable = 'off';
            app.FittingLimitsadvanceddefaultsshownPanel = uipanel(app.UIFigure);
            app.FittingLimitsadvanceddefaultsshownPanel.Title = 'Fitting Limits (advanced; defaults shown)';
            app.FittoTaboveCheckBox = uicheckbox(app.FittingLimitsadvanceddefaultsshownPanel);
            app.FittoTaboveCheckBox.Text = 'Fit to T above?';
            app.X1e15cm3Label_2 = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.X1e15cm3Label_2.Text = 'X1e15 cm(-3)';
            app.LowTlimitEditFieldLabel = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.LowTlimitEditFieldLabel.Text = 'Low T limit';
            app.LowTlimitEditField = uieditfield(app.FittingLimitsadvanceddefaultsshownPanel, 'numeric');
            app.Uppern0limitEditFieldLabel = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.Uppern0limitEditFieldLabel.Text = 'Upper n0 limit';
            app.Uppern0limitEditField = uieditfield(app.FittingLimitsadvanceddefaultsshownPanel, 'numeric');
            app.Uppern0limitEditField.Value = 100;
            app.Lowern0LimitEditFieldLabel = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.Lowern0LimitEditFieldLabel.Text = 'Lower n0 Limit';
            app.Lowern0LimitEditField = uieditfield(app.FittingLimitsadvanceddefaultsshownPanel, 'numeric');
            app.Lowern0LimitEditField.Value = 0.01;
            app.X1e15cm3Label_3 = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.X1e15cm3Label_3.Text = 'X1e15 cm(-3)';
            app.LowerDefectLevelLimitEditFieldLabel = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.LowerDefectLevelLimitEditFieldLabel.Text = 'Lower Defect Level Limit';
            app.LowerDefectLevelLimitEditField = uieditfield(app.FittingLimitsadvanceddefaultsshownPanel, 'numeric');
            app.LowerDefectLevelLimitEditField.Value = 30;
            app.meVLabel_9 = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.meVLabel_9.Text = 'meV';
            app.UpperDefectLevelLimitEditFieldLabel = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.UpperDefectLevelLimitEditFieldLabel.Text = 'Upper Defect Level Limit';
            app.UpperDefectLevelLimitEditField = uieditfield(app.FittingLimitsadvanceddefaultsshownPanel, 'numeric');
            app.UpperDefectLevelLimitEditField.Value = 300;
            app.meVLabel_10 = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.meVLabel_10.Text = 'meV';
            app.LowerDefectDensityLimitEditFieldLabel = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.LowerDefectDensityLimitEditFieldLabel.Text = 'Lower Defect Density Limit';
            app.LowerDefectDensityLimitEditField = uieditfield(app.FittingLimitsadvanceddefaultsshownPanel, 'numeric');
            app.LowerDefectDensityLimitEditField.Value = 0.1;
            app.m1Label_9 = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.m1Label_9.Text = 'm(-1)';
            app.UpperDefectDensityLimitEditFieldLabel = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.UpperDefectDensityLimitEditFieldLabel.Text = 'Upper Defect Density Limit';
            app.UpperDefectDensityLimitEditField = uieditfield(app.FittingLimitsadvanceddefaultsshownPanel, 'numeric');
            app.UpperDefectDensityLimitEditField.Value = 100;
            app.m1Label_10 = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.m1Label_10.Text = 'm(-1)';
            app.LowerF1F2LimitEditFieldLabel = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.LowerF1F2LimitEditFieldLabel.Text = 'Lower |F1F2| Limit';
            app.LowerF1F2LimitEditField = uieditfield(app.FittingLimitsadvanceddefaultsshownPanel, 'numeric');
            app.LowerF1F2LimitEditField.Value = 0.01;
            app.UpperF1F2LimitLabel = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.UpperF1F2LimitLabel.Text = 'Upper |F1F2| Limit';
            app.UpperF1F2LimitEditField = uieditfield(app.FittingLimitsadvanceddefaultsshownPanel, 'numeric');
            app.UpperF1F2LimitEditField.Value = 0.3;
            app.RNGseedEditFieldLabel = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.RNGseedEditFieldLabel.Text = 'RNG seed';
            app.RNGseedEditField = uieditfield(app.FittingLimitsadvanceddefaultsshownPanel, 'numeric');
            app.RNGseedEditField.Value = 2;
            app.NumberofGuessesEditFieldLabel = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.NumberofGuessesEditFieldLabel.Text = 'Number of Guesses';
            app.NumberofGuessesEditField = uieditfield(app.FittingLimitsadvanceddefaultsshownPanel, 'numeric');
            app.NumberofGuessesEditField.Value = 100;
            app.Label = uilabel(app.FittingLimitsadvanceddefaultsshownPanel);
            app.Label.Text = '+1';
            app.WeightedDataCheckBox = uicheckbox(app.FittingLimitsadvanceddefaultsshownPanel);
            app.WeightedDataCheckBox.Text = 'Weighted Data? (default no weighting)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Carriers

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end